classdef about_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        HTML        matlab.ui.control.HTML
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    
    methods (Access = public)
        
        function centerPos = CalCenterPos(app, mainpos)
            centerPos = zeros(1,4);
            appPos = app.UIFigure.Position;
            centerPos(1) = mainpos(1) + 0.5*(mainpos(3)-appPos(3));
            centerPos(2) = mainpos(2) + 0.5*(mainpos(4)-appPos(4));
            centerPos(3:4) = appPos(3:4);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mainpos)
            app.CallingApp = mainapp;
            app.UIFigure.Position = app.CalCenterPos(mainpos);
            app.CallingApp.isAboutOpen = true;
            app.HTML.HTMLSource = fullfile(pwd,"readme.html");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.isAboutOpen = false;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '关于';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HTML = uihtml(app.GridLayout);
            app.HTML.HTMLSource = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = about_ui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end