classdef psins_trj_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PSINSUIFigure        matlab.ui.Figure
        Menu                 matlab.ui.container.Menu
        MenuLoad             matlab.ui.container.Menu
        MenuSave             matlab.ui.container.Menu
        Menu_2               matlab.ui.container.Menu
        GridLayout           matlab.ui.container.GridLayout
        ActionGrid           matlab.ui.container.GridLayout
        OperationGrid        matlab.ui.container.GridLayout
        btn_AddAction        matlab.ui.control.Button
        btn_DelAction        matlab.ui.control.Button
        btn_PreTrack         matlab.ui.control.Button
        btn_Simulate         matlab.ui.control.Button
        ActionTable          matlab.ui.control.Table
        GridLayout2          matlab.ui.container.GridLayout
        Panel                matlab.ui.container.Panel
        GridLayout3          matlab.ui.container.GridLayout
        degEditFieldLabel    matlab.ui.control.Label
        InitPitch            matlab.ui.control.NumericEditField
        degEditField_2Label  matlab.ui.control.Label
        InitRoll             matlab.ui.control.NumericEditField
        degEditField_3Label  matlab.ui.control.Label
        InitYaw              matlab.ui.control.NumericEditField
        msEditFieldLabel     matlab.ui.control.Label
        InitVe               matlab.ui.control.NumericEditField
        msEditField_2Label   matlab.ui.control.Label
        InitVn               matlab.ui.control.NumericEditField
        msEditField_3Label   matlab.ui.control.Label
        InitVu               matlab.ui.control.NumericEditField
        degEditField_4Label  matlab.ui.control.Label
        InitLat              matlab.ui.control.NumericEditField
        degEditField_5Label  matlab.ui.control.Label
        InitLng              matlab.ui.control.NumericEditField
        mEditFieldLabel      matlab.ui.control.Label
        InitH                matlab.ui.control.NumericEditField
        Label_2              matlab.ui.control.Label
        ts                   matlab.ui.control.NumericEditField
        Label                matlab.ui.control.Label
        Repet                matlab.ui.control.NumericEditField
        Button               matlab.ui.control.Button
        sLabel               matlab.ui.control.Label
        StepBox              matlab.ui.control.DropDown
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        isAboutOpen = false;
%         actionCnt = 0; % 动作总数
%         
%         selectedRow = -1; % 当前选中行号
%         tableRowNum = cell(200,1); % 序号列
%         tableData = cell(200,6); % 表格数据
%         
%         actionName = {}; % 动作名称
%         rowDataMap = containers.Map; % 新增行数据
%         
%         previewStep = 0.01; % 轨迹预览仿真步长s        
%         simuTs = 0.01;      % 仿真数据采样时间s
%         
%         simuStep = 0.005;  % 仿真步长s
%         stepBoxIndex = 1;  % 仿真步长时间框下拉选项索引
%         
%         initSins = [];       
            
    end
    
    properties (Access = private)
        AboutUi;    % 关于页显示
        
        actionCnt = 0; % 动作总数
        
        selectedRow = -1; % 当前选中行号
        tableRowNum = cell(200,1); % 序号列
        tableData = cell(200,6); % 表格数据
        
        actionName = {}; % 动作名称
        rowDataMap = containers.Map; % 新增行数据
        
        previewStep = 0.01; % 轨迹预览仿真步长s        
        simuTs = 0.01;      % 仿真数据采样时间s
        
        simuStep = 0.005;  % 仿真步长s
        stepBoxIndex = 1;  % 仿真步长时间框下拉选项索引
        stepBoxOptions = {}; % 仿真步长选项
        
        initSins = [];             
    end
    
    methods (Access = private)
        % 初始化表格数据        
        function InitRowData(app)
            % 指令名称
            app.actionName = {'uniform','accelerate','deaccelerate','turnleft','turnright','headup','headdown',...
                'rollleft','rollright','coturnleft','coturnright','8turn','sturn','climb','descent','user'};
            rowData = cell(length(app.actionName),1);
            % 未完成 指令行数据提示
            row = 1;
            %          动作名称 last  w    a   var
            rowData{row} = {'', 10, inf, inf, inf}; row = row + 1; % uniform
            rowData{row} = {'', 10, inf,  0 , inf}; row = row + 1; % accelerate
            rowData{row} = {'', 10, inf,  0 , inf}; row = row + 1; % deaccelerate
            rowData{row} = {'', 10,  0 , inf, inf}; row = row + 1; % turnleft
            rowData{row} = {'', 10,  0 , inf, inf}; row = row + 1; % turnright
            rowData{row} = {'', 10,  0 , inf, inf}; row = row + 1; % headup
            rowData{row} = {'', 10,  0 , inf, inf}; row = row + 1; % headdown
            rowData{row} = {'', 10,  0 , inf,  0 }; row = row + 1; % rollleft
            rowData{row} = {'', 10,  0 , inf,  0 }; row = row + 1; % rollright
            rowData{row} = {'', 10,  0 , inf,  0 }; row = row + 1; % coturnleft
            rowData{row} = {'', 10,  0 , inf,  0 }; row = row + 1; % coturnright
            rowData{row} = {'', 10,  0 , inf,  0 }; row = row + 1; % 8turn
            rowData{row} = {'', 10,  0 , inf,  0 }; row = row + 1; % sturn
            rowData{row} = {'', 10,  0 , inf,  0 }; row = row + 1; % climb
            rowData{row} = {'', 10,  0 , inf,  0 }; row = row + 1; % descent
            rowData{row} = {'', 10,  0 ,  0 , inf}; % 16个指令      % user
            % 更新动作名称
            for i = 1:row
                rowData{i}{1} = app.actionName{i};               
            end           
            app.rowDataMap = containers.Map(app.actionName, rowData);  
        end
        
        % 表格行号刷新函数
        function updateRowNum(app)
            if(-1==app.selectedRow)
                app.tableData(app.actionCnt,1) = {app.actionCnt};
            else
                app.tableData(1:app.actionCnt,1) = num2cell((1:app.actionCnt)');
            end            
        end
        
        % 更新轨迹
        function [seg, pos] = UpdateTrack(app)
            % 轨迹设置
            seg = trjsegment([],'init',0);
            for i = 1:app.actionCnt
                row = app.ActionTable.Data(i,:);
                seg = trjsegment(seg,row{2},row{3},row{4},row{5},row{6});
            end
            if(isempty(seg.wat))
                pos = -1;
            else
                pos = trjspos(app.initSins.avp, seg.wat, app.previewStep, app.initSins.repet);
            end 
        end
        
        % 更新轨迹显示
        function UpdateFigure(app, pos)
            dpos = pos2dxyz(pos);
            plot3(app.UIAxes, 0,0,0,'rp','MarkerSize',10); hold(app.UIAxes,"on");
            plot3(app.UIAxes,dpos(:,1),dpos(:,2),dpos(:,3),'LineWidth',1.5); hold(app.UIAxes,"on");
            pos0 = app.initSins.avp(7:9);
            str = sprintf('Start:[%.4f, %.4f, %.0f]',pos0(1),pos0(2),pos0(3));
            legend(app.UIAxes,str,'Location',"northeast");
            app.UIAxes.View = [0 90];
        end
        
        % 解析读取文件
        function [tabledata, cnt, initSins] = ParseFile(app, filename)
            f = fopen(filename,"r");
            tabledata = cell(size(app.tableData));
            cnt = 0;
            initSins.ts = 0; initSins.avp = zeros(9,1);
            initField = split(fgetl(f),',');
            for i=1:9, initSins.avp(i) = str2double(initField{i}); end
            initSins.ts = str2double(initField{10});
            initSins.repet = str2double(initField{11});
            initSins.simuStepBoxIndex = str2double(initField{12});
            while ~feof(f)
                cnt = cnt + 1;
                field = split(fgetl(f),',',2);
                for i =3:6, field{i} = str2double(field{i}); end
                tabledata(cnt,2:6) = field(2:end);
            end
        end
        
        % 更新导航初始化参数结构体
        function UpdateInit(app)
            global glv
            % 导航初始参数设置
            % 采样时间
            app.initSins.ts = app.ts.Value; if app.initSins.ts==0, app.initSins.ts = 1; end
            % 初始位置
            app.initSins.avp(1) = app.InitPitch.Value*glv.deg;
            app.initSins.avp(2) = app.InitRoll.Value*glv.deg;
            app.initSins.avp(3) = app.InitYaw.Value*glv.deg;
            app.initSins.avp(4) = app.InitVe.Value;
            app.initSins.avp(5) = app.InitVn.Value;
            app.initSins.avp(6) = app.InitVu.Value;
            app.initSins.avp(7) = app.InitLat.Value;
            app.initSins.avp(8) = app.InitLng.Value;
            app.initSins.avp(9) = app.InitH.Value;
            % 重复次数
            app.initSins.repet  = fix(app.Repet.Value);
            if(app.initSins.repet < 1), app.initSins.repet = 1; end
            % 仿真步长
            app.initSins.simuStepTime = app.simuStep; 
        end
        
        % 更新导航初始化参数显示
        function DisplayInit(app)
            global glv
            % 更新导航初始参数显示
            app.ts.Value = app.initSins.ts;
            app.InitPitch.Value = app.initSins.avp(1)/glv.deg;
            app.InitRoll.Value  = app.initSins.avp(2)/glv.deg;
            app.InitYaw.Value   = app.initSins.avp(3)/glv.deg;
            app.InitVe.Value    = app.initSins.avp(4);
            app.InitVn.Value    = app.initSins.avp(5);
            app.InitVu.Value    = app.initSins.avp(6);            
            app.InitLat.Value   = app.initSins.avp(7);
            app.InitLng.Value   = app.initSins.avp(8);
            app.InitH.Value     = app.initSins.avp(9);
            app.Repet.Value     = app.initSins.repet;
            app.StepBox.Value   = app.stepBoxOptions{app.initSins.simuStepBoxIndex};
        end
        
        % 生成仿真数据
        function results = GenerateSimu(app, seg)
            % 生成仿真数据
            if(isempty(seg.wat))
                results = -1;
            else
                trj = trjsimu(app.initSins.avp, seg.wat, app.initSins.simuStepTime, app.initSins.repet);
                if(isempty(trj)),results = 0; return; end
                trj = app.SampleSimu(trj);
                time = fix(clock);
                filename = sprintf('%4d%02d%02d_%02d%02d%02d_trj.mat',time(1),time(2),time(3),time(4),time(5),time(6));
                save(filename, 'trj');
                insplot(trj.avp);
                imuplot(trj.imu);
                results = 1;
            end   
        end
        
        % 输出采样数据
        function trj = SampleSimu(app, trj)
            global glv;
            if(app.initSins.ts > app.initSins.simuStepTime)
                frq = fix(app.initSins.ts/app.initSins.simuStepTime);
            else
                frq = 1;
            end
            
            trj.ts = app.initSins.ts;
            trj.avp(:,7:8) = trj.avp(:,7:8);
            trj.avp = trj.avp(1:frq:end,:);
            trj.avp0(7:8) = trj.avp0(7:8)*glv.deg;
            trj.imu = trj.imu(1:frq:end,:);
            trj.imu(:,1:6) = trj.imu(:,1:6) / app.initSins.simuStepTime * trj.ts;
        end
        
        % 清除画布内容
        function ResetFig(app)
            hold(app.UIAxes,"off");
            plot3(app.UIAxes,0,0,0);
            app.UIAxes.View=[0,90];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            h = app.ActionTable;
            h.ColumnName = {'序号','动作','持续时间','角速度','加速度','额外参数'};
            h.ColumnWidth = {50, 'auto', 'auto', 'auto', 'auto', 'auto'};
            % 初始化动作与表格数据
            app.InitRowData();
            set(h, 'ColumnFormat',({[] app.actionName}));                                               
            app.ActionTable.Data = app.tableData;
            % 导航全局变量
            glvs
            % 导航参数结构体
            app.initSins.ts=0; 
            app.initSins.avp=zeros(9,1); 
            app.initSins.repet = 1; 
            app.initSins.simuStepBoxIndex = 1;
            % 初始化Figure设置
            xlabel(app.UIAxes, '\Delta\itP_{E} m');
            ylabel(app.UIAxes, '\Delta\itP_{N} m');
            zlabel(app.UIAxes, '\Delta\itP_{H} m');
            % 初始化仿真步长选项cell
            app.stepBoxOptions = app.StepBox.Items;
        end

        % Button pushed function: btn_AddAction
        function btn_AddActionButtonPushed(app, event)
            app.tableData = app.ActionTable.Data; % 读取当前表格数据
            app.actionCnt = app.actionCnt + 1;
            % 判断是否有选中行: 有-选中行后插入 无-尾部插入
            if(app.selectedRow > -1 && app.selectedRow < app.actionCnt)
                app.tableData(app.selectedRow+2:app.actionCnt,2:6) = app.tableData(app.selectedRow+1:app.actionCnt-1,2:6);
                app.tableData(app.selectedRow+1,2:6) = app.rowDataMap(app.actionName{1});
            else
                app.tableData(app.actionCnt,2:6) = app.rowDataMap(app.actionName{1});
            end
            % 更新行号和表格数据
            app.updateRowNum();
            app.ActionTable.Data = app.tableData;                                  
        end

        % Button pushed function: btn_DelAction
        function btn_DelActionButtonPushed(app, event)
            % 判断 存在数据行 且 选择行号有效
            if((app.actionCnt > 0) && (app.selectedRow <= app.actionCnt) && (app.selectedRow >0))
                app.actionCnt = app.actionCnt - 1;
                app.tableData = app.ActionTable.Data;
                % 清除选中行
                app.tableData(app.selectedRow,:) = [];
                % 更新行号和数据
                app.updateRowNum();
                app.ActionTable.Data = app.tableData;
                % 如果行数全部删除，则将选择行号初始化为-1
                if(0 == app.actionCnt), app.selectedRow = -1; end
            end            
        end

        % Button pushed function: btn_PreTrack
        function btn_PreTrackButtonPushed(app, event)
            app.UpdateInit();
            [~, pos] = app.UpdateTrack();
            if(pos ~= -1)
                app.UpdateFigure(pos);    
            end                    
        end

        % Button pushed function: btn_Simulate
        function btn_SimulateButtonPushed(app, event)
            app.UpdateInit();
            [seg,pos] = app.UpdateTrack();
            if(pos ~= -1)
                app.ResetFig();
                app.UpdateFigure(pos);   
                app.GenerateSimu(seg);
            end  
        end

        % Menu selected function: MenuSave
        function MenuSaveSelected(app, event)
            [filename, path] = uiputfile('*.txt','Save File As');
            if(filename ~= 0)
                app.UpdateInit();
                f = fopen([path filename],"w");
                
                avp = app.initSins.avp; t = app.initSins.ts; rep = app.initSins.repet;
                fprintf(f,'%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%d,%d\r\n',avp(1),avp(2),avp(3),avp(4),avp(5),avp(6),...
                    avp(7),avp(8),avp(9),t,rep,app.initSins.simuStepBoxIndex);
                data = app.ActionTable.Data;
                for i = 1:app.actionCnt
                    fprintf(f,'%d,%s,%f,%f,%f,%f\r\n',data{i,1},data{i,2},data{i,3},data{i,4},data{i,5},data{i,6});
                end
            end
        end

        % Menu selected function: MenuLoad
        function MenuLoadSelected(app, event)
            [filename, path] = uigetfile('*.txt','Load File As');
            if(filename ~= 0)
                [app.tableData, app.actionCnt, app.initSins] = ParseFile(app, [path filename]);
                app.DisplayInit();
                % 更新表格数据
                app.selectedRow = 0;
                app.updateRowNum();
                app.ActionTable.Data = app.tableData;
                % 更新轨迹
                [~,pos] = app.UpdateTrack();
                if(pos ~= -1)
                    app.UpdateFigure(pos);
                end
            end
        end

        % Cell selection callback: ActionTable
        function ActionTableCellSelection(app, event)
            indices = event.Indices;
            app.selectedRow = indices(1);
        end

        % Display data changed function: ActionTable
        function ActionTableDisplayDataChanged(app, event)
            if(app.selectedRow <= app.actionCnt)                
                if(2 == event.InteractionColumn)      
                    app.ActionTable.Data(app.selectedRow,2:6) = app.rowDataMap(app.ActionTable.DisplayData{app.selectedRow,2});
                end
                app.tableData = app.ActionTable.DisplayData;
            else
                app.ActionTable.Data = app.tableData;
            end              
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.ResetFig();
        end

        % Value changed function: StepBox
        function StepBoxValueChanged(app, event)
            value = app.StepBox.Value;
            % 遍历选项确定仿真步长时间 2021-12-13
            for i=1:length(app.stepBoxOptions)
                if(value == app.stepBoxOptions{i})
                    app.initSins.simuStepBoxIndex = i;
                end
            end
            app.simuStep = str2double(app.stepBoxOptions{app.initSins.simuStepBoxIndex});
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            if(~app.isAboutOpen)
                app.AboutUi = about_ui(app,app.PSINSUIFigure.Position);
            end                      
        end

        % Close request function: PSINSUIFigure
        function PSINSUIFigureCloseRequest(app, event)
            delete(app.AboutUi);
            delete(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PSINSUIFigure = uifigure('Visible', 'off');
            app.PSINSUIFigure.Name = 'PSINS工具箱轨迹生成器';
            app.PSINSUIFigure.CloseRequestFcn = createCallbackFcn(app, @PSINSUIFigureCloseRequest, true);
            app.Menu = uimenu(app.PSINSUIFigure);
            app.Menu.Text = '文件';
            app.MenuLoad = uimenu(app.Menu);
            app.MenuLoad.MenuSelectedFcn = createCallbackFcn(app, @MenuLoadSelected, true);
            app.MenuLoad.Text = '加载轨迹列表';
            app.MenuSave = uimenu(app.Menu);
            app.MenuSave.MenuSelectedFcn = createCallbackFcn(app, @MenuSaveSelected, true);
            app.MenuSave.Text = '保存轨迹列表';
            app.Menu_2 = uimenu(app.PSINSUIFigure);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '关于';
            app.GridLayout = uigridlayout(app.PSINSUIFigure);
            app.ActionGrid = uigridlayout(app.GridLayout);
            app.OperationGrid = uigridlayout(app.ActionGrid);
            app.btn_AddAction = uibutton(app.OperationGrid, 'push');
            app.btn_AddAction.ButtonPushedFcn = createCallbackFcn(app, @btn_AddActionButtonPushed, true);
            app.btn_AddAction.Text = '添加行';
            app.btn_DelAction = uibutton(app.OperationGrid, 'push');
            app.btn_DelAction.ButtonPushedFcn = createCallbackFcn(app, @btn_DelActionButtonPushed, true);
            app.btn_DelAction.Text = '删除行';
            app.btn_PreTrack = uibutton(app.OperationGrid, 'push');
            app.btn_PreTrack.ButtonPushedFcn = createCallbackFcn(app, @btn_PreTrackButtonPushed, true);
            app.btn_PreTrack.Text = '预览轨迹';
            app.btn_Simulate = uibutton(app.OperationGrid, 'push');
            app.btn_Simulate.ButtonPushedFcn = createCallbackFcn(app, @btn_SimulateButtonPushed, true);
            app.btn_Simulate.Text = '生成仿真数据';
            app.ActionTable = uitable(app.ActionGrid);
            app.ActionTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'};
            app.ActionTable.RowName = {};
            app.ActionTable.ColumnEditable = true;
            app.ActionTable.CellSelectionCallback = createCallbackFcn(app, @ActionTableCellSelection, true);
            app.ActionTable.DisplayDataChangedFcn = createCallbackFcn(app, @ActionTableDisplayDataChanged, true);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Panel = uipanel(app.GridLayout2);
            app.Panel.Title = '初始参数';
            app.GridLayout3 = uigridlayout(app.Panel);
            app.degEditFieldLabel = uilabel(app.GridLayout3);
            app.degEditFieldLabel.Text = '俯仰角deg';
            app.InitPitch = uieditfield(app.GridLayout3, 'numeric');
            app.InitPitch.Limits = [-90 90];
            app.degEditField_2Label = uilabel(app.GridLayout3);
            app.degEditField_2Label.Text = '横滚角deg';
            app.InitRoll = uieditfield(app.GridLayout3, 'numeric');
            app.InitRoll.Limits = [-180 180];
            app.degEditField_3Label = uilabel(app.GridLayout3);
            app.degEditField_3Label.Text = '航向角deg';
            app.InitYaw = uieditfield(app.GridLayout3, 'numeric');
            app.InitYaw.Limits = [-180 180];
            app.msEditFieldLabel = uilabel(app.GridLayout3);
            app.msEditFieldLabel.Text = '东向速度ms';
            app.InitVe = uieditfield(app.GridLayout3, 'numeric');
            app.msEditField_2Label = uilabel(app.GridLayout3);
            app.msEditField_2Label.Text = '北向速度ms';
            app.InitVn = uieditfield(app.GridLayout3, 'numeric');
            app.msEditField_3Label = uilabel(app.GridLayout3);
            app.msEditField_3Label.Text = '天向速度ms';
            app.InitVu = uieditfield(app.GridLayout3, 'numeric');
            app.degEditField_4Label = uilabel(app.GridLayout3);
            app.degEditField_4Label.Text = '纬度deg';
            app.InitLat = uieditfield(app.GridLayout3, 'numeric');
            app.InitLat.Limits = [-90 90];
            app.degEditField_5Label = uilabel(app.GridLayout3);
            app.degEditField_5Label.Text = '经度deg';
            app.InitLng = uieditfield(app.GridLayout3, 'numeric');
            app.InitLng.Limits = [-180 180];
            app.mEditFieldLabel = uilabel(app.GridLayout3);
            app.mEditFieldLabel.Text = '高度m';
            app.InitH = uieditfield(app.GridLayout3, 'numeric');
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '采样时间';
            app.ts = uieditfield(app.GridLayout3, 'numeric');
            app.ts.Limits = [0 10];
            app.ts.Value = 0.01;
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '重复次数';
            app.Repet = uieditfield(app.GridLayout3, 'numeric');
            app.Repet.Limits = [0 Inf];
            app.Repet.ValueDisplayFormat = '%.0f';
            app.Repet.Value = 1;
            app.Button = uibutton(app.GridLayout3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '清除轨迹';
            app.sLabel = uilabel(app.GridLayout3);
            app.sLabel.Text = '仿真步长s';
            app.StepBox = uidropdown(app.GridLayout3);
            app.StepBox.Items = {'0.005', '0.001', '0.0005', '0.0001'};
            app.StepBox.ValueChangedFcn = createCallbackFcn(app, @StepBoxValueChanged, true);
            app.StepBox.Value = '0.005';
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, '仿真轨迹预览')
            xlabel(app.UIAxes, '\Delta\itP_{E}')
            ylabel(app.UIAxes, '\Delta\itP_{N}')
            % Show the figure after all components are created
            app.PSINSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = psins_trj_ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PSINSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PSINSUIFigure)
        end
    end
end